classdef EmbryoTimePredict < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EmbryoTimePredictLabel          matlab.ui.control.Label
        Ver10Label                      matlab.ui.control.Label
        ControlPanel                    matlab.ui.container.Panel
        ImagePanel                      matlab.ui.container.Panel
        UIAxes2                         matlab.ui.control.UIAxes
        Procedure2EmbryoareainputPanel  matlab.ui.container.Panel
        ResolutionLabel                 matlab.ui.control.Label
        ResolutionEditField             matlab.ui.control.NumericEditField
        StandardareaLabel               matlab.ui.control.Label
        StandardareaEditField           matlab.ui.control.NumericEditField
        PreviewOutputTextArea           matlab.ui.control.TextArea
        PreviewOutputTextAreaLabel      matlab.ui.control.Label
        AutoPanel                       matlab.ui.container.Panel
        P3CheckBox                      matlab.ui.control.CheckBox
        P2CheckBox                      matlab.ui.control.CheckBox
        P1CheckBox                      matlab.ui.control.CheckBox
        AutomaticOperationButton        matlab.ui.control.StateButton
        ProcessingEmbryoSequenceNumberGauge  matlab.ui.control.LinearGauge
        ProcessingEmbryoSequenceNumberGaugeLabel  matlab.ui.control.Label
        Procedure1InputorAutomaticidentifyembryocyclePanel  matlab.ui.container.Panel
        ORLabel                         matlab.ui.control.Label
        CyclesmultipleembryosseparatebyEditField  matlab.ui.control.EditField
        CyclesmultipleembryosseparatebyEditFieldLabel  matlab.ui.control.Label
        Procedure1Lamp                  matlab.ui.control.Lamp
        Procedure1LampLabel             matlab.ui.control.Label
        CyclePredictButton              matlab.ui.control.Button
        OperatingModeDropDown           matlab.ui.control.DropDown
        OperatingModeDropDownLabel      matlab.ui.control.Label
        Procedure3TimepredictPanel      matlab.ui.container.Panel
        KerasPathEditField              matlab.ui.control.EditField
        TensorflowPathLabel             matlab.ui.control.Label
        ModelButtonGroup                matlab.ui.container.ButtonGroup
        NormalButton                    matlab.ui.control.RadioButton
        FastButton                      matlab.ui.control.RadioButton
        PilotLamp                       matlab.ui.control.Lamp
        PilotLampLabel                  matlab.ui.control.Label
        TimePredictButton               matlab.ui.control.Button
        BrowseButton                    matlab.ui.control.Button
        PathEditField                   matlab.ui.control.EditField
        PathEditFieldLabel              matlab.ui.control.Label
        Image                           matlab.ui.control.Image
    end


    properties (Access = public)
        pathBase=struct('pathname','','folderlist',''); % Description % Description
    end
    
    properties (Access = private)
        DialogApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            global pathname workpath folder_list
            if strcmp(app.OperatingModeDropDown.Value,app.OperatingModeDropDown.Items{1})
                folder_list=uigetfile_n_dir';
                floder_new=cellfun(@(x) [x,'\'],folder_list(:,1),'UniformOutput',false);
                folder_list(:,1)=floder_new;
                workpath=[folder_list{1}];
            else
                [filename, pathname,~]=uigetfile('*.xls','Select A Duallist');
                workpath=fullfile(pathname,filename);
%                 app.StandardPathEditField.Value=pathname;
                [~, folder_list] = xlsread(workpath);
                folder_list = folder_list(strcmpi('T',folder_list(:,6)),:);
                try
                    readcell([folder_list{1,1},'stacks/matchlist.xls']);
                catch
                    floder_new=cellfun(@(x) [pathname,x],folder_list(:,1),'UniformOutput',false);
                    folder_list(:,1)=floder_new;
                end
            end
            if ~isempty(workpath)
                app.PathEditField.Value=workpath;
            end
        end

        % Button pushed function: TimePredictButton
        function TimePredictButtonPushed(app, event)
            global folder_list workpath
            app.PilotLamp.Color=[0 1 1];pause(0.0001);
            modelgroup={'Fast','Normal'};
            Model=char(modelgroup([app.FastButton.Value,app.NormalButton.Value]));
            DAPI_fixed_out(workpath,Model,app.StandardareaEditField.Value,app.ResolutionEditField.Value);
            TensorflowPath=app.KerasPathEditField.Value;
            Path = [pwd '\'];
            Ps = [Path 'predictlist-GUI-s.py'];
            Pm = [Path 'predictlist-GUI-m.py'];
            Pl = [Path 'predictlist-GUI-l.py'];
            if strcmp(Model,modelgroup{1})
                RunAdd=['python ' Ps ' --folder ' workpath ' --relyPath ' Path];
            else
                RunAdd=['python ' Ps ' --folder ' workpath ' --relyPath ' Path 
                    ' && ' 'python ' Pm ' --folder ' workpath ' --relyPath ' Path 
                    ' && ' 'python ' Pl ' --folder ' workpath ' --relyPath ' Path];
            end
            RunTimePredict_add=['activate && conda activate ',TensorflowPath,' && ',RunAdd];
            [~,cmdout]=system(RunTimePredict_add);

            predict_out(workpath)
            app.PilotLamp.Color=[0 1 0];
        end

        % Callback function
        function PushToolClicked(app, event)
   
        end

        % Button pushed function: CyclePredictButton
        function CyclePredictButtonPushed(app, event)
            global pathname workpath folder_list
            app.Procedure1Lamp.Color=[0 1 1];pause(0.0001);
            cycleOut = cycleout(workpath);
            app.CyclesmultipleembryosseparatebyEditField.Value = cycleOut;
            app.Procedure1Lamp.Color=[0 1 0];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'lion-6620633.png';
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control Panel';
            app.PathEditFieldLabel = uilabel(app.ControlPanel);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.ControlPanel, 'text');
            app.PathEditField.Value = 'D:\GUI\test\';
            app.BrowseButton = uibutton(app.ControlPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.Procedure3TimepredictPanel = uipanel(app.ControlPanel);
            app.Procedure3TimepredictPanel.Title = 'Procedure 3: Time predict';
            app.TimePredictButton = uibutton(app.Procedure3TimepredictPanel, 'push');
            app.TimePredictButton.ButtonPushedFcn = createCallbackFcn(app, @TimePredictButtonPushed, true);
            app.TimePredictButton.Text = 'TimePredict';
            app.PilotLampLabel = uilabel(app.Procedure3TimepredictPanel);
            app.PilotLampLabel.Text = 'Pilot Lamp';
            app.PilotLamp = uilamp(app.Procedure3TimepredictPanel);
            app.ModelButtonGroup = uibuttongroup(app.Procedure3TimepredictPanel);
            app.ModelButtonGroup.Title = 'Model';
            app.FastButton = uiradiobutton(app.ModelButtonGroup);
            app.FastButton.Text = 'Fast';
            app.NormalButton = uiradiobutton(app.ModelButtonGroup);
            app.NormalButton.Text = 'Normal';
            app.NormalButton.Value = true;
            app.TensorflowPathLabel = uilabel(app.Procedure3TimepredictPanel);
            app.TensorflowPathLabel.Text = 'Keras Path';
            app.KerasPathEditField = uieditfield(app.Procedure3TimepredictPanel, 'text');
            app.KerasPathEditField.Value = 'C:\Users\Baohuihan\anaconda3\envs\keras\';
            app.OperatingModeDropDownLabel = uilabel(app.ControlPanel);
            app.OperatingModeDropDownLabel.Text = 'Operating Mode';
            app.OperatingModeDropDown = uidropdown(app.ControlPanel);
            app.OperatingModeDropDown.Items = {'Single', 'Duallist'};
            app.OperatingModeDropDown.Value = 'Single';
            app.Procedure1InputorAutomaticidentifyembryocyclePanel = uipanel(app.ControlPanel);
            app.Procedure1InputorAutomaticidentifyembryocyclePanel.Title = 'Procedure 1: Input or Automatic identify embryo cycle';
            app.CyclePredictButton = uibutton(app.Procedure1InputorAutomaticidentifyembryocyclePanel, 'push');
            app.CyclePredictButton.ButtonPushedFcn = createCallbackFcn(app, @CyclePredictButtonPushed, true);
            app.CyclePredictButton.Text = 'CyclePredict';
            app.Procedure1LampLabel = uilabel(app.Procedure1InputorAutomaticidentifyembryocyclePanel);
            app.Procedure1LampLabel.Text = 'Procedure 1';
            app.Procedure1Lamp = uilamp(app.Procedure1InputorAutomaticidentifyembryocyclePanel);
            app.CyclesmultipleembryosseparatebyEditFieldLabel = uilabel(app.Procedure1InputorAutomaticidentifyembryocyclePanel);
            app.CyclesmultipleembryosseparatebyEditFieldLabel.Text = 'Cycles (multiple embryos separate by ",")';
            app.CyclesmultipleembryosseparatebyEditField = uieditfield(app.Procedure1InputorAutomaticidentifyembryocyclePanel, 'text');
            app.ORLabel = uilabel(app.Procedure1InputorAutomaticidentifyembryocyclePanel);
            app.ORLabel.Text = 'OR';
            app.AutoPanel = uipanel(app.ControlPanel);
            app.AutoPanel.Title = 'Auto Panel';
            app.ProcessingEmbryoSequenceNumberGaugeLabel = uilabel(app.AutoPanel);
            app.ProcessingEmbryoSequenceNumberGaugeLabel.Text = 'Processing Embryo Sequence Number';
            app.ProcessingEmbryoSequenceNumberGauge = uigauge(app.AutoPanel, 'linear');
            app.ProcessingEmbryoSequenceNumberGauge.Limits = [0 1];
            app.ProcessingEmbryoSequenceNumberGauge.MajorTicks = [0 1];
            app.AutomaticOperationButton = uibutton(app.AutoPanel, 'state');
            app.AutomaticOperationButton.Text = 'AutomaticOperation';
            app.P1CheckBox = uicheckbox(app.AutoPanel);
            app.P1CheckBox.Text = 'P1';
            app.P1CheckBox.Value = true;
            app.P2CheckBox = uicheckbox(app.AutoPanel);
            app.P2CheckBox.Text = 'P2';
            app.P2CheckBox.Value = true;
            app.P3CheckBox = uicheckbox(app.AutoPanel);
            app.P3CheckBox.Text = 'P3';
            app.P3CheckBox.Value = true;
            app.PreviewOutputTextAreaLabel = uilabel(app.ControlPanel);
            app.PreviewOutputTextAreaLabel.Text = 'Preview Output';
            app.PreviewOutputTextArea = uitextarea(app.ControlPanel);
            app.PreviewOutputTextArea.Value = {'Waiting...'};
            app.Procedure2EmbryoareainputPanel = uipanel(app.ControlPanel);
            app.Procedure2EmbryoareainputPanel.Title = 'Procedure 2: Embryo area input';
            app.StandardareaEditField = uieditfield(app.Procedure2EmbryoareainputPanel, 'numeric');
            app.StandardareaEditField.ValueDisplayFormat = '%.0f';
            app.StandardareaEditField.Value = 74460;
            app.StandardareaLabel = uilabel(app.Procedure2EmbryoareainputPanel);
            app.StandardareaLabel.Text = 'Standard area';
            app.ResolutionEditField = uieditfield(app.Procedure2EmbryoareainputPanel, 'numeric');
            app.ResolutionEditField.Value = 0.066;
            app.ResolutionLabel = uilabel(app.Procedure2EmbryoareainputPanel);
            app.ResolutionLabel.Text = 'Resolution';
            app.ImagePanel = uipanel(app.ControlPanel);
            app.ImagePanel.Title = 'Image Panel';
            app.UIAxes2 = uiaxes(app.ImagePanel);
            app.UIAxes2.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.Ver10Label = uilabel(app.UIFigure);
            app.Ver10Label.Text = 'Ver. 1.0';
            app.EmbryoTimePredictLabel = uilabel(app.UIFigure);
            app.EmbryoTimePredictLabel.Text = 'Embryo Time Predict';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EmbryoTimePredict

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end