classdef NuclearRecognitionProcess < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Toolbar                        matlab.ui.container.Toolbar
        PushTool                       matlab.ui.container.toolbar.PushTool
        PushTool2                      matlab.ui.container.toolbar.PushTool
        NuclearrecognitionLabel        matlab.ui.control.Label
        Ver01Label                     matlab.ui.control.Label
        AutoPanel                      matlab.ui.container.Panel
        P3CheckBox                     matlab.ui.control.CheckBox
        P2CheckBox                     matlab.ui.control.CheckBox
        P1CheckBox                     matlab.ui.control.CheckBox
        AutomaticOperationButton       matlab.ui.control.StateButton
        ProcessingEmbryoSequenceNumberGauge  matlab.ui.control.LinearGauge
        ProcessingEmbryoSequenceNumberGaugeLabel  matlab.ui.control.Label
        ControlPanel                   matlab.ui.container.Panel
        SegmentationButtonGroup        matlab.ui.container.ButtonGroup
        DeepCellButton                 matlab.ui.control.RadioButton
        CellPoseButton                 matlab.ui.control.RadioButton
        TimeDimensionSwitch            matlab.ui.control.Switch
        TimeDimensionSwitchLabel       matlab.ui.control.Label
        WorkStateEditField             matlab.ui.control.EditField
        WorkStateEditFieldLabel        matlab.ui.control.Label
        OperatingModeDropDown          matlab.ui.control.DropDown
        OperatingModeDropDownLabel     matlab.ui.control.Label
        Procedure3DataprocessingOutputPanel  matlab.ui.container.Panel
        ShowAreaButton                 matlab.ui.control.Button
        AreaDetectEditField            matlab.ui.control.EditField
        AreaDetectEditFieldLabel       matlab.ui.control.Label
        EdgeCheckBox                   matlab.ui.control.CheckBox
        ThickLabel                     matlab.ui.control.Label
        ThresholdEditField_3           matlab.ui.control.NumericEditField
        WholeLabel                     matlab.ui.control.Label
        SingleLabel                    matlab.ui.control.Label
        ThresholdEditField_2           matlab.ui.control.NumericEditField
        ThresholdCheckBox              matlab.ui.control.CheckBox
        ThresholdEditField             matlab.ui.control.NumericEditField
        Procedure3Lamp                 matlab.ui.control.Lamp
        Procedure3LampLabel            matlab.ui.control.Label
        Label3DOUTPUTButton            matlab.ui.control.Button
        Procedure2CellposemodulePanel  matlab.ui.container.Panel
        TryButton                      matlab.ui.control.Button
        pngCheckBox                    matlab.ui.control.CheckBox
        tifCheckBox                    matlab.ui.control.CheckBox
        npyCheckBox                    matlab.ui.control.CheckBox
        DiameterEditField              matlab.ui.control.NumericEditField
        DiameterEditFieldLabel         matlab.ui.control.Label
        OutputFileTypeLabel            matlab.ui.control.Label
        ModelButtonGroup               matlab.ui.container.ButtonGroup
        cytoButton                     matlab.ui.control.RadioButton
        nucleiButton                   matlab.ui.control.RadioButton
        cyto2Button                    matlab.ui.control.RadioButton
        Procedure2Lamp                 matlab.ui.control.Lamp
        Procedure2LampLabel            matlab.ui.control.Label
        NuclearSegmentationButton      matlab.ui.control.Button
        Procedure1RawdatatransformtoinputtingdataPanel  matlab.ui.container.Panel
        ImagingChannelEditField        matlab.ui.control.NumericEditField
        ImagingChannelEditFieldLabel   matlab.ui.control.Label
        ResizeEditField                matlab.ui.control.NumericEditField
        ResizeEditFieldLabel           matlab.ui.control.Label
        Procedure1Lamp                 matlab.ui.control.Lamp
        Procedure1LampLabel            matlab.ui.control.Label
        raw2inputButton                matlab.ui.control.Button
        BrowseButton                   matlab.ui.control.Button
        PathEditField                  matlab.ui.control.EditField
        PathEditFieldLabel             matlab.ui.control.Label
        ImagePanel                     matlab.ui.container.Panel
        TimeLabel                      matlab.ui.control.Label
        Button_4                       matlab.ui.control.Button
        EditField_2                    matlab.ui.control.NumericEditField
        Button_3                       matlab.ui.control.Button
        StackLabel                     matlab.ui.control.Label
        Button_2                       matlab.ui.control.Button
        EditField                      matlab.ui.control.NumericEditField
        Button                         matlab.ui.control.Button
        QuickLookButton                matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        Image                          matlab.ui.control.Image
    end


    properties (Access = public)
        pathBase=struct('pathname','','folderlist',''); % Description % Description
    end
    
    properties (Access = private)
        DialogApp % Description
        DialogApp2
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            global pathname workpath folder_list
            if strcmp(app.OperatingModeDropDown.Value,app.OperatingModeDropDown.Items{1})
                folder_list=uigetfile_n_dir';
                floder_new=cellfun(@(x) [x,'\'],folder_list(:,1),'UniformOutput',false);
                folder_list(:,1)=floder_new;
                workpath=[folder_list{1}];
            else
                [filename, pathname,~]=uigetfile('*.xls','Select A Duallist');
                workpath=fullfile(pathname,filename);
%                 app.StandardPathEditField.Value=pathname;
                [~, folder_list] = xlsread(workpath);
                folder_list = folder_list(strcmpi('T',folder_list(:,6)),:);
                try
                    readcell([folder_list{1,1},'stacks/matchlist.xls']);
                catch
                    floder_new=cellfun(@(x) [path ame,x],folder_list(:,1),'UniformOutput',false);
                    folder_list(:,1)=floder_new;
                end
            end
            if ~isempty(workpath)
                app.PathEditField.Value=workpath;
            end
        end

        % Button pushed function: raw2inputButton
        function raw2inputButtonPushed(app, event)
            global Runcellpose folder_list Work_em time_dim embryo_channel
            try
%                 channel_inf=eval(folder_list{Work_em,3});
                if app.ImagingChannelEditField.Value==0
                    if strcmp(app.OperatingModeDropDown.Value,app.OperatingModeDropDown.Items{2})
                        app.ImagingChannelEditField.Value=embryo_channel(Work_em);
                    end
                    if exist([app.PathEditField.Value,'raw2input_try\','Try_S.xml'])
                        Try_s=readstruct([app.PathEditField.Value,'raw2input_try\','Try_S.xml']);
                        try
                            app.ImagingChannelEditField.Value=Try_s.Channel;
                        end
                    end
                end
            end
            if strcmp(app.TimeDimensionSwitch.Value,'Off')
                if exist([app.PathEditField.Value,'raw2input_try\','Try_S.xml'])
                    Try_s=readstruct([app.PathEditField.Value,'raw2input_try\','Try_S.xml']);
                    app.ResizeEditField.Value=Try_s.Resize;
                end
                app.Procedure1Lamp.Color=[0 1 1];pause(0.0001);
                Runcellpose=cellpose_raw2input(app.PathEditField.Value,app.ResizeEditField.Value,app.ImagingChannelEditField.Value);
            else
                if exist([app.PathEditField.Value,'raw2input_try\','Try_S.xml'])
                    Try_s=readstruct([app.PathEditField.Value,'raw2input_try\','Try_S.xml']);
                    app.ResizeEditField.Value=Try_s.Resize;
                    app.Procedure1Lamp.Color=[0 1 1];pause(0.0001);
                    [Runcellpose,time_dim]=cellpose_raw2input_time(app.PathEditField.Value,app.ResizeEditField.Value,app.ImagingChannelEditField.Value);
                end
            end
            
            app.Procedure1Lamp.Color=[0 1 0];
            app.WorkStateEditField.Value='-Done-';
            pause(0.0001);
        end

        % Button pushed function: NuclearSegmentationButton
        function NuclearSegmentationButtonPushed(app, event)
            global Runcellpose time_dim
            app.Procedure2Lamp.Color=[0 1 1];pause(0.0001);
            modelgroup={'--pretrained_model cyto2','--pretrained_model nuclei','--pretrained_model cyto'};
            model=char(modelgroup([app.cyto2Button.Value,app.nucleiButton.Value,app.cytoButton.Value]));
            savegroup={' --save_tif',' --save_png'};
            saveFile=char(savegroup([app.tifCheckBox.Value,app.pngCheckBox.Value]));
            if size(saveFile,1)>1,saveFile=[saveFile(1,:),saveFile(2,:)];end
            
            if strcmp(app.TimeDimensionSwitch.Value,'Off')
                if exist([app.PathEditField.Value,'raw2input_try\','Try_S.xml'])
                    Try_s=readstruct([app.PathEditField.Value,'raw2input_try\','Try_S.xml']);
                    try
                        app.DiameterEditField.Value=Try_s.Diameter;
                        app.ImagingChannelEditField.Value=Try_s.Channel;
                    end
                end
                if app.DiameterEditField.Value==0
                    diameter=' --diameter 0.';
                else
                    diameter=[' --diameter ',num2str(app.DiameterEditField.Value)];
                end
                Runcellpose_add=[Runcellpose,model,' --chan 0',diameter,' --use_gpu',saveFile];
                if app.npyCheckBox.Value==0
                    Runcellpose_add=[Runcellpose_add,' --no_npy'];
                end
                
                Runcellpose_add=['activate && conda activate D:\cellpose && ',Runcellpose_add,' --verbose'];
                system(Runcellpose_add);
            else
                Try_s=readstruct([app.PathEditField.Value,'raw2input_try\','Try_S.xml']);
                Diameter=Try_s.Diameter;
                for timeIndex=1:time_dim 
                    app.DiameterEditField.Value=Diameter(timeIndex);
                    if app.DiameterEditField.Value==0
                        diameter=' --diameter 0.';
                    else
                        diameter=[' --diameter ',num2str(app.DiameterEditField.Value)];
                    end
                    Runcellpose_add=[Runcellpose{timeIndex},model,' --chan 0',diameter,' --use_gpu',saveFile];
                    if app.npyCheckBox.Value==0
                        Runcellpose_add=[Runcellpose_add,' --no_npy'];
                    end
                    Runcellpose_add=['activate && conda activate D:\cellpose && ',Runcellpose_add,' --verbose'];
                    system(Runcellpose_add);
                end
            end
%             --pretrained_model cyto2 --chan 0 --diameter 0. --use_gpu --save_tif
            app.Procedure2Lamp.Color=[0 1 0];
            app.WorkStateEditField.Value='-Done-';
            pause(0.0001);
        end

        % Button pushed function: Label3DOUTPUTButton
        function Label3DOUTPUTButtonPushed(app, event)
            global savemask_path time_dim Work_em embryo_channel
            %% channel
            try
                if app.ImagingChannelEditField.Value==0
                    if strcmp(app.OperatingModeDropDown.Value,app.OperatingModeDropDown.Items{2})
                        app.ImagingChannelEditField.Value=embryo_channel(Work_em);
                    end
                    if exist([app.PathEditField.Value,'raw2input_try\','Try_S.xml'])
                        Try_s=readstruct([app.PathEditField.Value,'raw2input_try\','Try_S.xml']);
                        try
                            app.ImagingChannelEditField.Value=Try_s.Channel;
                        end
                    end
                end
            end
            
            app.Procedure3Lamp.Color=[0 1 1];pause(0.0001);
            if strcmp(app.TimeDimensionSwitch.Value,'Off')
                mask_boundary_removal_new([app.PathEditField.Value,'raw2input\'],[app.PathEditField.Value,'Intermediate\']);
            else
                for timeIndex=1:time_dim
                    mask_boundary_removal_new([app.PathEditField.Value,'raw2input\time',num2str(timeIndex,'%04u'),'\'],[app.PathEditField.Value,'Intermediate\']);
                end
            end
            app.PathEditField.Value=replace(app.PathEditField.Value,'/','\');
            savemask_path=replace(app.PathEditField.Value,'\stacks\','\masks\');
            cellpose_bwmat_live_while([app.PathEditField.Value,'Intermediate\'],savemask_path,3)
            if app.ThresholdCheckBox.Value
                delete_mask(app.ThresholdEditField.Value,app.ThresholdEditField_2.Value,app.EdgeCheckBox.Value,app.ImagingChannelEditField.Value,str2num(app.AreaDetectEditField.Value),...
                    savemask_path,app.PathEditField.Value);
                cellpose_bwmat_live_while([app.PathEditField.Value,'Intermediate\'],savemask_path,app.ThresholdEditField_3.Value);
            end
            app.Procedure3Lamp.Color=[0 1 0];
            app.WorkStateEditField.Value='-Done-';
            pause(0.0001);
        end

        % Button pushed function: ShowAreaButton
        function ShowAreaButtonPushed(app, event)
            global savemask_path
            app.PathEditField.Value=replace(app.PathEditField.Value,'/','\');
            AreaMask(app.ThresholdEditField.Value,app.ThresholdEditField_2.Value,app.EdgeCheckBox.Value,app.ImagingChannelEditField.Value,str2num(app.AreaDetectEditField.Value),...
                savemask_path,app.PathEditField.Value);
        end

        % Button pushed function: QuickLookButton
        function QuickLookButtonPushed(app, event)
            global masks sort_nat_name stack_num savemask_path
            stackvalue = app.EditField.Value;
            timevalue = app.EditField_2.Value;
            masks=load([savemask_path,'time',num2str(timevalue,'%04u'),'mask.mat'],'mask_stack');
            masks=logical(masks.mask_stack);
            stack_num=size(masks,3);
            imgDir  = dir([app.PathEditField.Value,'time',num2str(timevalue,'%04u'),'*.tif']);
            if size(imgDir,1)==0
                imgDir  = dir([app.PathEditField.Value,'*.tif']);
            end
            sort_nat_name = sort_nat({imgDir.name});
            Tif_img=imread([app.PathEditField.Value,sort_nat_name{stackvalue}]);
            Tif_img=Tif_img(:,:,app.ImagingChannelEditField.Value);
            hold(app.UIAxes,"off")
            imagesc(app.UIAxes,Tif_img)
            caxis(app.UIAxes,'auto')
            colormap(app.UIAxes,"jet")
            hold(app.UIAxes,"on")
%             contour_bw=bwperim(masks(:,:,stackvalue));
            masks_stack=masks(:,:,stackvalue).*double(max(max(Tif_img)));
%             h=image(app.UIAxes,contour_bw.*double(max(max(Tif_img))));
%             set(h,'alphadata',~eq(contour_bw,0));
            contour(app.UIAxes,masks_stack,'LineWidth',0.5)
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            global masks sort_nat_name stack_num
            value = app.EditField.Value;
            if value>stack_num,value=stack_num;end
            if value<1,value=1;end
            hold(app.UIAxes,"off")
            Tif_img=imread([app.PathEditField.Value,sort_nat_name{value}]);
            Tif_img=Tif_img(:,:,app.ImagingChannelEditField.Value);
            imagesc(app.UIAxes,Tif_img)
            hold(app.UIAxes,"on")
            masks_stack=masks(:,:,value).*double(max(max(Tif_img)));
            contour(app.UIAxes,masks_stack,'LineWidth',0.5)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            global masks sort_nat_name stack_num
            value = app.EditField.Value;
            hold(app.UIAxes,"off")
            if value<stack_num,value=value+1;end
            Tif_img=imread([app.PathEditField.Value,sort_nat_name{value}]);
            Tif_img=Tif_img(:,:,app.ImagingChannelEditField.Value);
            imagesc(app.UIAxes,Tif_img)
            hold(app.UIAxes,"on")
            masks_stack=masks(:,:,value).*double(max(max(Tif_img)));
            contour(app.UIAxes,masks_stack,'LineWidth',0.5)
            app.EditField.Value=value;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            global masks sort_nat_name
            value = app.EditField.Value;
            hold(app.UIAxes,"off")
            if value>0,value=value-1;end
            Tif_img=imread([app.PathEditField.Value,sort_nat_name{value}]);
            Tif_img=Tif_img(:,:,app.ImagingChannelEditField.Value);
            imagesc(app.UIAxes,Tif_img)
            hold(app.UIAxes,"on")
            masks_stack=masks(:,:,value).*double(max(max(Tif_img)));
            contour(app.UIAxes,masks_stack,'LineWidth',0.5)
            app.EditField.Value=value;
        end

        % Value changed function: AutomaticOperationButton
        function AutomaticOperationButtonValueChanged(app, event)
            global folder_list workpath Work_em embryo_channel

            embryo_channel=[];
            embryo_process={};
            if isempty(folder_list)
                folder_list=app.pathBase.folderlist;
            end
%             workpath=app.PathEditField.Value;
            if strcmp(app.OperatingModeDropDown.Value,app.OperatingModeDropDown.Items{1})
                work_num=size(folder_list,1);
                app.ProcessingEmbryoSequenceNumberGauge.Limits=[0,work_num];
                app.ProcessingEmbryoSequenceNumberGauge.MajorTicks=[0:1:work_num];
                
                
                for Work_em=1:size(folder_list,1)
                    app.PathEditField.Value=folder_list{Work_em,1};
                    if app.P1CheckBox.Value
                        app.Procedure1Lamp.Color=[1 1 1];
                        pause(0.001);
                        raw2inputButtonPushed(app, event)
                    end
                    if app.P2CheckBox.Value
                        app.Procedure2Lamp.Color=[1 1 1];
                        pause(0.001);
                        NuclearSegmentationButtonPushed(app, event)
                    end
                    if app.P3CheckBox.Value
                        app.Procedure3Lamp.Color=[1 1 1];
                        pause(0.001);
                        Label3DOUTPUTButtonPushed(app, event)
                    end
                    app.ProcessingEmbryoSequenceNumberGauge.Value=Work_em;
                end
            else
                for list_I=1:size(folder_list,1)
                    raw = readcell([folder_list{list_I,1},'stacks/matchlist.xls']);
                    subfloder_new=cellfun(@(x) [folder_list{list_I,1},'stacks/',x],raw(:,3),'UniformOutput',false);
                    embryo_process=[embryo_process;subfloder_new];
                    channel_inf=eval(folder_list{list_I,3});
                    embryo_channel=[embryo_channel,repmat(channel_inf(1),1,size(subfloder_new,1))];
    %                     sub_num=cell2mat(raw(:,cellfun(@isnumeric,raw(1,:))));
    %                     sub_list=raw;
    %                     sub_list(:,~cellfun(@ischar,raw(1,:)))={''};
                end


                work_num=size(embryo_process,1);
                app.ProcessingEmbryoSequenceNumberGauge.Limits=[0,work_num];
                app.ProcessingEmbryoSequenceNumberGauge.MajorTicks=[0:1:work_num];
                for Work_em=1:work_num
                    pause(0.001);
                    app.PathEditField.Value=embryo_process{Work_em,1};
                    if app.P1CheckBox.Value
                        app.Procedure1Lamp.Color=[1 1 1];
                        pause(0.001);
                        raw2inputButtonPushed(app, event)
                    end
                    if app.P2CheckBox.Value
                        app.Procedure2Lamp.Color=[1 1 1];
                        pause(0.001);
                        NuclearSegmentationButtonPushed(app, event)
                    end
                    if app.P3CheckBox.Value
                        app.Procedure3Lamp.Color=[1 1 1];
                        pause(0.001);
                        Label3DOUTPUTButtonPushed(app, event)
                    end
                    app.ProcessingEmbryoSequenceNumberGauge.Value=Work_em;
                end
            end
        end

        % Callback function: PushTool
        function PushToolClicked(app, event)
            system('activate && conda activate D:\cellpose && python -m cellpose');
        end

        % Callback function: PushTool2
        function PushTool2Clicked(app, event)
            global workpath folder_list
            app.DialogApp=CheckOverlap;
            app.DialogApp.pathBase.folderlist=folder_list;
            app.DialogApp.OperatingModeDropDown.Value=app.OperatingModeDropDown.Value;
            try
                app.DialogApp.PathEditField.Value=workpath;
            catch
                app.DialogApp.PathEditField.Value=app.PathEditField.Value;
            end
        end

        % Button pushed function: TryButton
        function TryButtonPushed(app, event)
            global workpath folder_list
            app.DialogApp2=CellPoseTry;
            app.DialogApp2.pathBase.folderlist=folder_list;
            app.DialogApp2.OperatingModeDropDown.Value=app.OperatingModeDropDown.Value;
            try
                app.DialogApp2.PathEditField.Value=workpath;
            catch
                app.DialogApp2.PathEditField.Value=app.PathEditField.Value;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = 'Cellpose-zsh.png';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Icon = 'Overlap-zsh.png';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'lion-6620633.png';
            app.ImagePanel = uipanel(app.UIFigure);
            app.ImagePanel.Title = 'Image Panel';
            app.UIAxes = uiaxes(app.ImagePanel);
            title(app.UIAxes, 'Mask')
            xlabel(app.UIAxes, 'Driection X')
            ylabel(app.UIAxes, 'Driection Y')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'parula')
            app.QuickLookButton = uibutton(app.ImagePanel, 'push');
            app.QuickLookButton.ButtonPushedFcn = createCallbackFcn(app, @QuickLookButtonPushed, true);
            app.QuickLookButton.Text = 'QuickLook';
            app.Button = uibutton(app.ImagePanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '<';
            app.EditField = uieditfield(app.ImagePanel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 1;
            app.Button_2 = uibutton(app.ImagePanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '>';
            app.StackLabel = uilabel(app.ImagePanel);
            app.StackLabel.Text = 'Stack';
            app.Button_3 = uibutton(app.ImagePanel, 'push');
            app.Button_3.Text = '<';
            app.EditField_2 = uieditfield(app.ImagePanel, 'numeric');
            app.EditField_2.Value = 1;
            app.Button_4 = uibutton(app.ImagePanel, 'push');
            app.Button_4.Text = '>';
            app.TimeLabel = uilabel(app.ImagePanel);
            app.TimeLabel.Text = 'Time';
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control Panel';
            app.PathEditFieldLabel = uilabel(app.ControlPanel);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.ControlPanel, 'text');
            app.BrowseButton = uibutton(app.ControlPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.Procedure1RawdatatransformtoinputtingdataPanel = uipanel(app.ControlPanel);
            app.Procedure1RawdatatransformtoinputtingdataPanel.Title = 'Procedure 1: Raw data transform to inputting data';
            app.raw2inputButton = uibutton(app.Procedure1RawdatatransformtoinputtingdataPanel, 'push');
            app.raw2inputButton.ButtonPushedFcn = createCallbackFcn(app, @raw2inputButtonPushed, true);
            app.raw2inputButton.Text = 'raw2input';
            app.Procedure1LampLabel = uilabel(app.Procedure1RawdatatransformtoinputtingdataPanel);
            app.Procedure1LampLabel.Text = 'Procedure 1';
            app.Procedure1Lamp = uilamp(app.Procedure1RawdatatransformtoinputtingdataPanel);
            app.ResizeEditFieldLabel = uilabel(app.Procedure1RawdatatransformtoinputtingdataPanel);
            app.ResizeEditFieldLabel.Text = 'Resize';
            app.ResizeEditField = uieditfield(app.Procedure1RawdatatransformtoinputtingdataPanel, 'numeric');
            app.ResizeEditField.Value = 0.2;
            app.ImagingChannelEditFieldLabel = uilabel(app.Procedure1RawdatatransformtoinputtingdataPanel);
            app.ImagingChannelEditFieldLabel.Text = 'Imaging Channel';
            app.ImagingChannelEditField = uieditfield(app.Procedure1RawdatatransformtoinputtingdataPanel, 'numeric');
            app.Procedure2CellposemodulePanel = uipanel(app.ControlPanel);
            app.Procedure2CellposemodulePanel.Title = 'Procedure 2: Cellpose module';
            app.NuclearSegmentationButton = uibutton(app.Procedure2CellposemodulePanel, 'push');
            app.NuclearSegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @NuclearSegmentationButtonPushed, true);
            app.NuclearSegmentationButton.Text = 'NuclearSegmentation';
            app.Procedure2LampLabel = uilabel(app.Procedure2CellposemodulePanel);
            app.Procedure2LampLabel.Text = 'Procedure 2';
            app.Procedure2Lamp = uilamp(app.Procedure2CellposemodulePanel);
            app.ModelButtonGroup = uibuttongroup(app.Procedure2CellposemodulePanel);
            app.ModelButtonGroup.Title = 'Model';
            app.cyto2Button = uiradiobutton(app.ModelButtonGroup);
            app.cyto2Button.Text = 'cyto2';
            app.cyto2Button.Value = true;
            app.nucleiButton = uiradiobutton(app.ModelButtonGroup);
            app.nucleiButton.Text = 'nuclei';
            app.cytoButton = uiradiobutton(app.ModelButtonGroup);
            app.cytoButton.Text = 'cyto';
            app.OutputFileTypeLabel = uilabel(app.Procedure2CellposemodulePanel);
            app.OutputFileTypeLabel.Text = 'Output File Type ';
            app.DiameterEditFieldLabel = uilabel(app.Procedure2CellposemodulePanel);
            app.DiameterEditFieldLabel.Text = 'Diameter';
            app.DiameterEditField = uieditfield(app.Procedure2CellposemodulePanel, 'numeric');
            app.npyCheckBox = uicheckbox(app.Procedure2CellposemodulePanel);
            app.npyCheckBox.Text = '.npy';
            app.tifCheckBox = uicheckbox(app.Procedure2CellposemodulePanel);
            app.tifCheckBox.Enable = 'off';
            app.tifCheckBox.Text = '.tif';
            app.tifCheckBox.Value = true;
            app.pngCheckBox = uicheckbox(app.Procedure2CellposemodulePanel);
            app.pngCheckBox.Text = '.png';
            app.TryButton = uibutton(app.Procedure2CellposemodulePanel, 'push');
            app.TryButton.ButtonPushedFcn = createCallbackFcn(app, @TryButtonPushed, true);
            app.TryButton.Text = 'Try';
            app.Procedure3DataprocessingOutputPanel = uipanel(app.ControlPanel);
            app.Procedure3DataprocessingOutputPanel.Title = 'Procedure 3: Data processing & Output';
            app.Label3DOUTPUTButton = uibutton(app.Procedure3DataprocessingOutputPanel, 'push');
            app.Label3DOUTPUTButton.ButtonPushedFcn = createCallbackFcn(app, @Label3DOUTPUTButtonPushed, true);
            app.Label3DOUTPUTButton.Text = {'Label3D'; '&'; 'OUTPUT'};
            app.Procedure3LampLabel = uilabel(app.Procedure3DataprocessingOutputPanel);
            app.Procedure3LampLabel.Text = 'Procedure 3';
            app.Procedure3Lamp = uilamp(app.Procedure3DataprocessingOutputPanel);
            app.ThresholdEditField = uieditfield(app.Procedure3DataprocessingOutputPanel, 'numeric');
            app.ThresholdEditField.Value = 0.35;
            app.ThresholdCheckBox = uicheckbox(app.Procedure3DataprocessingOutputPanel);
            app.ThresholdCheckBox.Text = 'Threshold';
            app.ThresholdCheckBox.Value = true;
            app.ThresholdEditField_2 = uieditfield(app.Procedure3DataprocessingOutputPanel, 'numeric');
            app.SingleLabel = uilabel(app.Procedure3DataprocessingOutputPanel);
            app.SingleLabel.Text = 'Single';
            app.WholeLabel = uilabel(app.Procedure3DataprocessingOutputPanel);
            app.WholeLabel.Text = 'Whole';
            app.ThresholdEditField_3 = uieditfield(app.Procedure3DataprocessingOutputPanel, 'numeric');
            app.ThresholdEditField_3.Value = 5;
            app.ThickLabel = uilabel(app.Procedure3DataprocessingOutputPanel);
            app.ThickLabel.Text = 'Thick';
            app.EdgeCheckBox = uicheckbox(app.Procedure3DataprocessingOutputPanel);
            app.EdgeCheckBox.Text = 'Edge';
            app.AreaDetectEditFieldLabel = uilabel(app.Procedure3DataprocessingOutputPanel);
            app.AreaDetectEditFieldLabel.Text = 'AreaDetect';
            app.AreaDetectEditField = uieditfield(app.Procedure3DataprocessingOutputPanel, 'text');
            app.AreaDetectEditField.Value = '[0 inf]';
            app.ShowAreaButton = uibutton(app.Procedure3DataprocessingOutputPanel, 'push');
            app.ShowAreaButton.ButtonPushedFcn = createCallbackFcn(app, @ShowAreaButtonPushed, true);
            app.ShowAreaButton.Text = 'ShowArea';
            app.OperatingModeDropDownLabel = uilabel(app.ControlPanel);
            app.OperatingModeDropDownLabel.Text = 'Operating Mode';
            app.OperatingModeDropDown = uidropdown(app.ControlPanel);
            app.OperatingModeDropDown.Items = {'Single', 'Duallist'};
            app.OperatingModeDropDown.Value = 'Single';
            app.WorkStateEditFieldLabel = uilabel(app.ControlPanel);
            app.WorkStateEditFieldLabel.Text = 'Work State';
            app.WorkStateEditField = uieditfield(app.ControlPanel, 'text');
            app.WorkStateEditField.Value = 'Ready';
            app.TimeDimensionSwitchLabel = uilabel(app.ControlPanel);
            app.TimeDimensionSwitchLabel.Text = 'TimeDimension';
            app.TimeDimensionSwitch = uiswitch(app.ControlPanel, 'slider');
            app.SegmentationButtonGroup = uibuttongroup(app.ControlPanel);
            app.SegmentationButtonGroup.Title = 'Segmentation';
            app.CellPoseButton = uiradiobutton(app.SegmentationButtonGroup);
            app.CellPoseButton.Text = 'CellPose';
            app.CellPoseButton.Value = true;
            app.DeepCellButton = uiradiobutton(app.SegmentationButtonGroup);
            app.DeepCellButton.Text = 'DeepCell';
            app.AutoPanel = uipanel(app.UIFigure);
            app.AutoPanel.Title = 'Auto Panel';
            app.ProcessingEmbryoSequenceNumberGaugeLabel = uilabel(app.AutoPanel);
            app.ProcessingEmbryoSequenceNumberGaugeLabel.Text = 'Processing Embryo Sequence Number';
            app.ProcessingEmbryoSequenceNumberGauge = uigauge(app.AutoPanel, 'linear');
            app.ProcessingEmbryoSequenceNumberGauge.Limits = [0 1];
            app.ProcessingEmbryoSequenceNumberGauge.MajorTicks = [0 1];
            app.AutomaticOperationButton = uibutton(app.AutoPanel, 'state');
            app.AutomaticOperationButton.ValueChangedFcn = createCallbackFcn(app, @AutomaticOperationButtonValueChanged, true);
            app.AutomaticOperationButton.Text = 'AutomaticOperation';
            app.P1CheckBox = uicheckbox(app.AutoPanel);
            app.P1CheckBox.Text = 'P1';
            app.P1CheckBox.Value = true;
            app.P2CheckBox = uicheckbox(app.AutoPanel);
            app.P2CheckBox.Text = 'P2';
            app.P2CheckBox.Value = true;
            app.P3CheckBox = uicheckbox(app.AutoPanel);
            app.P3CheckBox.Text = 'P3';
            app.P3CheckBox.Value = true;
            app.Ver01Label = uilabel(app.UIFigure);
            app.Ver01Label.Text = 'Ver. 0.1';
            app.NuclearrecognitionLabel = uilabel(app.UIFigure);
            app.NuclearrecognitionLabel.Text = 'Nuclear recognition';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NuclearRecognitionProcess

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end